classdef App_Horst_Vorwaertskinematik < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        Image                matlab.ui.control.Image
        HorstAnzeigenButton  matlab.ui.control.Button
        Theta6EditField      matlab.ui.control.NumericEditField
        Theta5EditField      matlab.ui.control.NumericEditField
        Theta4EditField      matlab.ui.control.NumericEditField
        Theta3EditField      matlab.ui.control.NumericEditField
        Theta2EditField      matlab.ui.control.NumericEditField
        Theta1EditField      matlab.ui.control.NumericEditField
        Achse6Slider         matlab.ui.control.Slider
        Achse6SliderLabel    matlab.ui.control.Label
        Achse5Slider         matlab.ui.control.Slider
        Achse5SliderLabel    matlab.ui.control.Label
        Achse4Slider         matlab.ui.control.Slider
        Achse4SliderLabel    matlab.ui.control.Label
        Achse3Slider         matlab.ui.control.Slider
        Achse3SliderLabel    matlab.ui.control.Label
        Achse2Slider         matlab.ui.control.Slider
        Achse2SliderLabel    matlab.ui.control.Label
        Achse1Slider         matlab.ui.control.Slider
        Achse1SliderLabel    matlab.ui.control.Label
    end

    
    properties (Access = public)
        %Angles of the joints
        theta1 
        theta2
        theta3
        theta4
        theta5
        theta6

        %Robot
        robot=importrobot('Horst900_Attemp2.urdf');
        config; %displayed configuration

        %Figure
        figureHorst;

      end


    % Callbacks that handle component events
    methods (Access = private)

        % Value changing function: Achse1Slider
        function Achse1SliderValueChanging(app, event)
            app.theta1 = event.Value;
            app.Theta1EditField.Value=app.theta1;
            assignin('base','theta1workspace',app.theta1);
            app.config(1).JointPosition=app.theta1*2*pi/360*(-1);
            show(app.robot,app.config,'Frames','off');            
            %graphical settings
            set(app.figureHorst, 'MenuBar', 'none');
            set(gca,'XColor', 'none','YColor','none','ZColor','none','ZLim',[0 1.3],'CameraPosition',[5.5,5.5,3])
            grid on; axis on;
            drawnow
        end

        % Value changing function: Achse2Slider
        function Achse2SliderValueChanging(app, event)
            app.theta2 = event.Value;     
            app.Theta2EditField.Value=app.theta2;
            app.theta2=app.theta2*(-1);
            assignin('base','theta2workspace',app.theta2);
            app.config(2).JointPosition=app.theta2*2*pi/360;
            show(app.robot,app.config,'Frames','off');
            %graphical settings
            set(app.figureHorst, 'MenuBar', 'none');
            set(gca,'XColor', 'none','YColor','none','ZColor','none','ZLim',[0 1.3],'CameraPosition',[5.5,5.5,3])
            grid on; axis on;
            drawnow
        end

        % Value changing function: Achse3Slider
        function Achse3SliderValueChanging(app, event)
            app.theta3 = event.Value;     
            app.Theta3EditField.Value=app.theta3;
            app.theta3=app.theta3*(-1);
            assignin('base','theta3workspace',app.theta3);
            app.config(3).JointPosition=app.theta3*2*pi/360;
            show(app.robot,app.config,'Frames','off');
            %graphical settings
            set(app.figureHorst, 'MenuBar', 'none');
            set(gca,'XColor', 'none','YColor','none','ZColor','none','ZLim',[0 1.3],'CameraPosition',[5.5,5.5,3])
            grid on; axis on;
            drawnow
        end

        % Value changing function: Achse4Slider
        function Achse4SliderValueChanging(app, event)
            app.theta4 = event.Value;     
            app.Theta4EditField.Value=app.theta4;
            assignin('base','theta4workspace',app.theta4);
            app.config(4).JointPosition=app.theta4*2*pi/360*(-1);
            show(app.robot,app.config,'Frames','off');
            %graphical settings
            set(app.figureHorst, 'MenuBar', 'none');
            set(gca,'XColor', 'none','YColor','none','ZColor','none','ZLim',[0 1.3],'CameraPosition',[5.5,5.5,3])
            grid on; axis on;
            drawnow
        end

        % Value changing function: Achse5Slider
        function Achse5SliderValueChanging(app, event)
            app.theta5 = event.Value;     
            app.Theta5EditField.Value=app.theta5;
            app.theta5=app.theta5-90;
            assignin('base','theta5workspace',app.theta5);
            app.config(5).JointPosition=app.theta5*2*pi/360*(-1);
            show(app.robot,app.config,'Frames','off');
            %graphical settings
            set(app.figureHorst, 'MenuBar', 'none');
            set(gca,'XColor', 'none','YColor','none','ZColor','none','ZLim',[0 1.3],'CameraPosition',[5.5,5.5,3])
            grid on; axis on;
            drawnow
        end

        % Value changing function: Achse6Slider
        function Achse6SliderValueChanging(app, event)
            app.theta6 = event.Value;     
            app.Theta6EditField.Value=app.theta6;
            assignin('base','theta6workspace',app.theta6);
            app.config(6).JointPosition=app.theta6*2*pi/360;
            show(app.robot,app.config,'Frames','off');
            %graphical settings
            set(app.figureHorst, 'MenuBar', 'none');
            set(gca,'XColor', 'none','YColor','none','ZColor','none','ZLim',[0 1.3],'CameraPosition',[5.5,5.5,3])
            grid on; axis on;
            drawnow
        end

        % Button pushed function: HorstAnzeigenButton
        function HorstAnzeigenButtonPushed(app, event)
            app.config = homeConfiguration(app.robot);
            
            %shows the robot nexto the app
            app.figureHorst=figure('Name','Horst 900','NumberTitle','off','Position',[764 200 600 425]);
            
            %URDF file correction of 90° in fifth axis
            app.config(5).JointPosition=90*2*pi/360;
            show(app.robot,app.config,'Frames','off');

            %remove menueBar from figure
            set(app.figureHorst, 'MenuBar', 'none');
            %remove axis and set cameraposition
            set(gca,'XColor', 'none','YColor','none','ZColor','none','ZLim',[0 1.3],'CameraPosition',[5.5,5.5,3])
            grid on; axis on;            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Achse1SliderLabel = uilabel(app.UIFigure);
            app.Achse1SliderLabel.Text = 'Achse1';
            app.Achse1Slider = uislider(app.UIFigure);
            app.Achse1Slider.Limits = [-176 176];
            app.Achse1Slider.MajorTicks = [-176 -135 -90 -45 0 45 90 135 176];
            app.Achse1Slider.ValueChangingFcn = createCallbackFcn(app, @Achse1SliderValueChanging, true);
            app.Achse2SliderLabel = uilabel(app.UIFigure);
            app.Achse2SliderLabel.Text = 'Achse2';
            app.Achse2Slider = uislider(app.UIFigure);
            app.Achse2Slider.Limits = [-16 86.5];
            app.Achse2Slider.MajorTicks = [-16 0 45 86];
            app.Achse2Slider.ValueChangingFcn = createCallbackFcn(app, @Achse2SliderValueChanging, true);
            app.Achse3SliderLabel = uilabel(app.UIFigure);
            app.Achse3SliderLabel.Text = 'Achse3';
            app.Achse3Slider = uislider(app.UIFigure);
            app.Achse3Slider.Limits = [-67 42];
            app.Achse3Slider.MajorTicks = [-67 -45 0 42];
            app.Achse3Slider.ValueChangingFcn = createCallbackFcn(app, @Achse3SliderValueChanging, true);
            app.Achse4SliderLabel = uilabel(app.UIFigure);
            app.Achse4SliderLabel.Text = 'Achse4';
            app.Achse4Slider = uislider(app.UIFigure);
            app.Achse4Slider.Limits = [-171 171];
            app.Achse4Slider.MajorTicks = [-171 -135 -90 -45 0 45 90 135 171];
            app.Achse4Slider.ValueChangingFcn = createCallbackFcn(app, @Achse4SliderValueChanging, true);
            app.Achse5SliderLabel = uilabel(app.UIFigure);
            app.Achse5SliderLabel.Text = 'Achse5';
            app.Achse5Slider = uislider(app.UIFigure);
            app.Achse5Slider.Limits = [-119 119];
            app.Achse5Slider.MajorTicks = [-119 -90 -45 0 45 90 119];
            app.Achse5Slider.ValueChangingFcn = createCallbackFcn(app, @Achse5SliderValueChanging, true);
            app.Achse6SliderLabel = uilabel(app.UIFigure);
            app.Achse6SliderLabel.Text = 'Achse6';
            app.Achse6Slider = uislider(app.UIFigure);
            app.Achse6Slider.Limits = [-300 300];
            app.Achse6Slider.MajorTicks = [-300 -180 -90 0 90 180 300];
            app.Achse6Slider.ValueChangingFcn = createCallbackFcn(app, @Achse6SliderValueChanging, true);
            app.Theta1EditField = uieditfield(app.UIFigure, 'numeric');
            app.Theta1EditField.ValueDisplayFormat = '%.0f °';
            app.Theta1EditField.Editable = 'off';
            app.Theta2EditField = uieditfield(app.UIFigure, 'numeric');
            app.Theta2EditField.ValueDisplayFormat = '%.0f °';
            app.Theta2EditField.Editable = 'off';
            app.Theta3EditField = uieditfield(app.UIFigure, 'numeric');
            app.Theta3EditField.ValueDisplayFormat = '%.0f °';
            app.Theta3EditField.Editable = 'off';
            app.Theta4EditField = uieditfield(app.UIFigure, 'numeric');
            app.Theta4EditField.ValueDisplayFormat = '%.0f °';
            app.Theta4EditField.Editable = 'off';
            app.Theta5EditField = uieditfield(app.UIFigure, 'numeric');
            app.Theta5EditField.ValueDisplayFormat = '%.0f °';
            app.Theta5EditField.Editable = 'off';
            app.Theta6EditField = uieditfield(app.UIFigure, 'numeric');
            app.Theta6EditField.ValueDisplayFormat = '%.0f °';
            app.Theta6EditField.Editable = 'off';
            app.HorstAnzeigenButton = uibutton(app.UIFigure, 'push');
            app.HorstAnzeigenButton.ButtonPushedFcn = createCallbackFcn(app, @HorstAnzeigenButtonPushed, true);
            app.HorstAnzeigenButton.Text = 'Horst Anzeigen';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'fruitcore-robotics-HORST900-6.png');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = App_Horst_Vorwaertskinematik

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end